classdef webImageBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ViewerPanel                     matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        PrevButton                      matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        HeaderPanel                     matlab.ui.container.Panel
        ISETImageFinderLabel            matlab.ui.control.Label
        SelectSourceDropDownLabel       matlab.ui.control.Label
        SelectSourceDropDown            matlab.ui.control.DropDown
        EnterkeywordsEditFieldLabel     matlab.ui.control.Label
        EnterkeywordsEditField          matlab.ui.control.EditField
        LoadLocalFolderButton           matlab.ui.control.Button
        FooterPanel                     matlab.ui.container.Panel
        ClickonanimagetoopenasanISETSceneLabel  matlab.ui.control.Label
        OpenFolderinImageBrowserButton  matlab.ui.control.Button
    end

    
    properties (Access= public)
        ImageArray matlab.ui.control.Image
    end
    properties (Access = private)
        fname
        imgGridColumns
        imgGridRows
        currentFolder
        currentImageFiles; % want array of loaded images, but no native image object
        desireThumbnailSize = 100; % pixels in each dimension
        imageSpacer = 10;
    end
    
    methods (Access = private)
        function ImageClickedCallback(app, event)
            %uialert(app.UIFigure,'Got a callback',"now what?")
            clickedImageIndex = event.Source.UserData;
            % we can get the clicked image here, but may not need it for
            % most things
            clickedImage = app.ImageArray(clickedImageIndex(1), clickedImageIndex(2));
            scene = sceneFromFile(clickedImage.ImageSource,'rgb');
            ieAddObject(scene); sceneWindow;

        end

        function updateimage(app,imagefile)
            
            try
                im = imread(imagefile);
                app.fname = imagefile;
                app.ClickonanimagetoopenasanISETSceneLabel.Text = imagefile;
            catch ME
                    % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end                     
        end
        
        function [imageWidth, imageHeight] =  calcImageSize(app)
            gridSize = app.ViewerPanel.InnerPosition;
            gridWidth = gridSize(3);
            gridHeight = gridSize(4);
            app.imgGridRows = floor(gridHeight / app.desireThumbnailSize);
            app.imgGridColumns = floor(gridWidth / app.desireThumbnailSize);
            app.imageSpacer = 10; % In Case
            imageWidth = floor(gridWidth / app.imgGridColumns) - app.imageSpacer;
            imageHeight = floor(gridHeight / app.imgGridRows) - app.imageSpacer;
        end
        
        function [imageFiles] = loadImages(app)
            jpegFiles = dir(fullfile(app.currentFolder,'*.jpg'));
            % there must be an easier way to sort out all image files!!
            %tiffFiles = dir(ourDir,'*.tif');
            imageFiles = jpegFiles; % only jpeg for now:(
%            for i = 1:length(jpegFiles)
%                loadedImages(i) = imread(fullfile(jpegFiles(i).folder, jpegFiles(i).name)); 
%            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Update the image and histograms
            % djc can't do this live if we haven't created the grid yet!S
            %updateimage(app, 'peppers.png');
            
            [imageWidth, imageHeight] = calcImageSize(app);
            
            for i = 1:app.imgGridRows
                for j = 1:app.imgGridColumns
                    app.ImageArray(i,j) = uiimage(app.ViewerPanel);
                    app.ImageArray(i,j).Position = [(j-1)*(imageWidth+app.imageSpacer) (i-1)*(imageHeight+app.imageSpacer) imageWidth imageHeight];
                    app.ImageArray(i,j).UserData = [i, j];
                    %background color might be a poor person's way to
                    %indicate selected?
                    %app.ImageArray(i,j).BackgroundColor = [0.851 0.3294 0.102];
                    app.ImageArray(i,j).ImageClickedFcn = createCallbackFcn(app, @ImageClickedCallback, true);
                end
            end

        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Callback function
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               app.fname = [p f];
               updateimage(app, app.fname);
            end
        end

        % Callback function
        function OpeninISETButtonPushed(app, event)
            scene = sceneFromFile(app.fname,'rgb');
            ieAddObject(scene); sceneWindow;
        end

        % Button pushed function: OpenFolderinImageBrowserButton
        function OpenFolderinImageBrowserButtonPushed(app, event)
            % djc hack to get a random folder
            if isdeployed
                uialert(app.UIFigure, 'Image Browser not supported in runtime. Sorry.', 'Image Browser not Available');
            else
                imageBrowser(app.currentFolder);            
            end

        end

        % Button pushed function: LoadLocalFolderButton
        function LoadLocalFolderButtonPushed(app, event)
            
            ourDir = uigetdir(); % can give it a default
            app.currentFolder = ourDir;
            app.currentImageFiles = loadImages(app); 
            
            index = 1;
            for i = 1:app.imgGridRows
                for j = 1:app.imgGridColumns
                    if index <= length(app.currentImageFiles)
                        ourImageFile = fullfile(app.currentImageFiles(index).folder,app.currentImageFiles(index).name); % always starts from beginning for now
                        %if app.ImageArray(i,j).ImageSource ~= ourImageFile
                        %need to find a way to optimize by not reloading
                        %when not needed! djc
                            app.ImageArray(i,j).ImageSource = ourImageFile;
                        %end
                        index = index + 1;
                    end
                end
            end
        end

        % Callback function
        function UIFigureButtonDown(app, event)
            uialert(app.UIFigure,'Figure Button down called.', "Okay?")
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            uialert(app.UIFigure,'Image Click called.', "Okay?")
            
        end

        % Size changed function: ViewerPanel
        function ViewerPanelSizeChanged(app, event)
            %position = app.ViewerPanel.Position;
            if ~isempty(app.ImageArray) % first time we get called image controls haven't been created yet
                [imageWidth, imageHeight] = calcImageSize(app);
                % have new position & sizes, need to re-layout!
                % app.imgGrid... now have the rows & columns too / maybe should
                % just be returned from calc??
                for i = 1:app.imgGridRows
                    for j = 1:app.imgGridColumns
                        app.ImageArray(i,j).Position = [(j-1)*(imageWidth+app.imageSpacer) (i-1)*(imageHeight+app.imageSpacer) imageWidth imageHeight];
                    end
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ISET Image Finder';
            app.ViewerPanel = uipanel(app.UIFigure);
            app.ViewerPanel.SizeChangedFcn = createCallbackFcn(app, @ViewerPanelSizeChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Enable = 'off';
            app.Image.Visible = 'off';
            app.PrevButton = uibutton(app.UIFigure, 'push');
            app.PrevButton.Enable = 'off';
            app.PrevButton.Text = '<-';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Enable = 'off';
            app.NextButton.Text = '->';
            app.HeaderPanel = uipanel(app.UIFigure);
            app.ISETImageFinderLabel = uilabel(app.HeaderPanel);
            app.ISETImageFinderLabel.Text = 'ISET Image Finder';
            app.SelectSourceDropDownLabel = uilabel(app.HeaderPanel);
            app.SelectSourceDropDownLabel.Text = 'Select Source';
            app.SelectSourceDropDown = uidropdown(app.HeaderPanel);
            app.SelectSourceDropDown.Items = {'Unsplash'};
            app.SelectSourceDropDown.Value = 'Unsplash';
            app.EnterkeywordsEditFieldLabel = uilabel(app.HeaderPanel);
            app.EnterkeywordsEditFieldLabel.Text = 'Enter keywords';
            app.EnterkeywordsEditField = uieditfield(app.HeaderPanel, 'text');
            app.LoadLocalFolderButton = uibutton(app.HeaderPanel, 'push');
            app.LoadLocalFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLocalFolderButtonPushed, true);
            app.LoadLocalFolderButton.Text = 'Load Local Folder';
            app.FooterPanel = uipanel(app.UIFigure);
            app.ClickonanimagetoopenasanISETSceneLabel = uilabel(app.FooterPanel);
            app.ClickonanimagetoopenasanISETSceneLabel.Text = 'Click on an image to open as an ISET Scene';
            app.OpenFolderinImageBrowserButton = uibutton(app.FooterPanel, 'push');
            app.OpenFolderinImageBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderinImageBrowserButtonPushed, true);
            app.OpenFolderinImageBrowserButton.Text = 'Open Folder in Image Browser';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = webImageBrowser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end